classdef ARES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainGrid                        matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        InfoGridLayout                  matlab.ui.container.GridLayout
        README                          matlab.ui.control.Hyperlink
        AntTutorial                     matlab.ui.control.Hyperlink
        PATutorial                      matlab.ui.control.Hyperlink
        Author                          matlab.ui.control.Label
        Repository                      matlab.ui.control.Hyperlink
        Relase                          matlab.ui.control.Label
        AppName                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerAmplifierTab               matlab.ui.container.Tab
        PAGridLayout                    matlab.ui.container.GridLayout
        PASettingsPanel                 matlab.ui.container.TabGroup
        InstrumentsTab                  matlab.ui.container.Tab
        PAInstrumentGridLayout          matlab.ui.container.GridLayout
        PADisconnectButton              matlab.ui.control.Button
        MeasurementDelaysEditFieldLabel  matlab.ui.control.Label
        SpectrumAnalyzerLabel           matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAMeasurementDelayValueField    matlab.ui.control.NumericEditField
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SignalGeneratorLabel            matlab.ui.control.Label
        PowerSupplyBLabel               matlab.ui.control.Label
        PowerSupplyALabel               matlab.ui.control.Label
        SpectrumAnalyzerStatusLabel     matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        SignalGeneratorStatusLabel      matlab.ui.control.Label
        PowerSupplyBDropDown            matlab.ui.control.DropDown
        PowerSupplyBStatusLabel         matlab.ui.control.Label
        PowerSupplyADropDown            matlab.ui.control.DropDown
        PowerSupplyAStatusLabel         matlab.ui.control.Label
        PowerSupplyTab                  matlab.ui.container.Tab
        PAPSUGridLayout                 matlab.ui.container.GridLayout
        SupplyChannelDropDownLabel      matlab.ui.control.Label
        SupplyModeLabel                 matlab.ui.control.Label
        SupplyVoltageStepValueField     matlab.ui.control.NumericEditField
        SupplyVoltageStepNameField      matlab.ui.control.Label
        SupplyVoltageStopValueField     matlab.ui.control.NumericEditField
        SupplyVoltageStopEditField      matlab.ui.control.Label
        SupplyVoltageStartValueField    matlab.ui.control.NumericEditField
        SupplyVoltageStartNameField     matlab.ui.control.Label
        LimitingCurrentALabel           matlab.ui.control.Label
        SupplyCurrentValueField         matlab.ui.control.NumericEditField
        SingleSweepSwitch               matlab.ui.control.Switch
        DrainGateSwitch                 matlab.ui.control.Switch
        PowerSupplyChannelDropDown      matlab.ui.control.DropDown
        PowerSupplyModeDropDown         matlab.ui.control.DropDown
        SignalAnalysisTab               matlab.ui.container.Tab
        PASAGridLayout                  matlab.ui.container.GridLayout
        SpectrumAnalyzerSettingsLabel   matlab.ui.control.Label
        SignalGeneratorSettingsLabel    matlab.ui.control.Label
        MeasurementTypeDropDownLabel    matlab.ui.control.Label
        ReferenceLevelValueField        matlab.ui.control.NumericEditField
        ReferenceLevelNameField         matlab.ui.control.Label
        SweepPointsValueField           matlab.ui.control.NumericEditField
        SweepPointsNameField            matlab.ui.control.Label
        SpanValueField                  matlab.ui.control.NumericEditField
        SpanNameField                   matlab.ui.control.Label
        PowerStep                       matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPower                        matlab.ui.control.NumericEditField
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPower                      matlab.ui.control.NumericEditField
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        FrequencyStep                   matlab.ui.control.NumericEditField
        FrequencyStepMHzLabel           matlab.ui.control.Label
        EndFrequency                    matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel            matlab.ui.control.Label
        StartFrequency                  matlab.ui.control.NumericEditField
        StartFrequencyMHzLabel          matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        CalibrationTab                  matlab.ui.container.Tab
        PACalGridLayout                 matlab.ui.container.GridLayout
        FixedCalibrationLabel           matlab.ui.control.Label
        DriverResponseLabel             matlab.ui.control.Label
        SmallSignalFrequencyResponseLabel  matlab.ui.control.Label
        OutputAttenuationdBEditFieldLabel  matlab.ui.control.Label
        LargeSignalDriverResponseFilenameField  matlab.ui.control.EditField
        LoadLargeSignalDriverResponseButton  matlab.ui.control.Button
        OutputSmallSignalFilenameField  matlab.ui.control.EditField
        InputAttenuationdBLabel         matlab.ui.control.Label
        CalibrationModeLabel            matlab.ui.control.Label
        LoadOuputSmallSignalButton      matlab.ui.control.Button
        InputSmallSignalFilenameField   matlab.ui.control.EditField
        LoadInputSmallSignalButton      matlab.ui.control.Button
        OutputAttenuationValueField     matlab.ui.control.NumericEditField
        InputAttenuationValueField      matlab.ui.control.NumericEditField
        CalibrationModeDropDown         matlab.ui.control.DropDown
        PATestSettingsPanel             matlab.ui.container.Panel
        PATestSettingsGridLayout        matlab.ui.container.GridLayout
        FrequencySingleDropDown         matlab.ui.control.DropDown
        FrequencySingleDropDownLabel    matlab.ui.control.Label
        ChannelVoltageDropDown          matlab.ui.control.DropDown
        ChannelVoltageDropDownLabel     matlab.ui.control.Label
        PSUChannelDropDown              matlab.ui.control.DropDown
        PSUChannelDropDownLabel         matlab.ui.control.Label
        PlotPAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        PAResultsTabs                   matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        PAPlotSingleGridLayout          matlab.ui.container.GridLayout
        SingleFrequencyPAPlot           matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        PAPlotSweepGridLayout           matlab.ui.container.GridLayout
        CompressionPointsPlot           matlab.ui.control.UIAxes
        PeakDEPAEPlot                   matlab.ui.control.UIAxes
        PeakGainPlot                    matlab.ui.control.UIAxes
        GainvsOutputPowerPlot           matlab.ui.control.UIAxes
        AntennaTab                      matlab.ui.container.Tab
        AntGridLayout                   matlab.ui.container.GridLayout
        TestandPlotSettingsPanel        matlab.ui.container.Panel
        AntTestSettingsGridLayout       matlab.ui.container.GridLayout
        StopTestButton                  matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        PlotResultsButton               matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        PlotAngleDropDown               matlab.ui.control.DropDown
        PlottingAngleLabel              matlab.ui.control.Label
        PlotFrequencyGHzDropDown        matlab.ui.control.DropDown
        PlotFrequencyGHzDropDownLabel   matlab.ui.control.Label
        AntennaSettings                 matlab.ui.container.TabGroup
        AntInstrumentsTab               matlab.ui.container.Tab
        AntInstrumentGridLayout         matlab.ui.container.GridLayout
        AntennaMeasurementDelayValueField  matlab.ui.control.NumericEditField
        MeasurementDelaysLabel          matlab.ui.control.Label
        AntennaDisconnectButton         matlab.ui.control.Button
        AntennaConnectButton            matlab.ui.control.Button
        EMCenterStatusLabel             matlab.ui.control.Label
        EMCenterLabel                   matlab.ui.control.Label
        EMCenterDropDown                matlab.ui.control.DropDown
        VNADropDown                     matlab.ui.control.DropDown
        VNAStatusLabel                  matlab.ui.control.Label
        VNALabel                        matlab.ui.control.Label
        VNATab                          matlab.ui.container.Tab
        AntVNAGridLayout                matlab.ui.container.GridLayout
        SweepPointsLabel                matlab.ui.control.Label
        VNASweepPoints                  matlab.ui.control.NumericEditField
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAMeasurement                  matlab.ui.control.DropDown
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        VNAStartFrequency               matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        AntTableGridLayout              matlab.ui.container.GridLayout
        TableSpeedSliderLabel_2         matlab.ui.control.Label
        ThetaSingleSweepSwitch          matlab.ui.control.Switch
        TableSpeedSlider                matlab.ui.control.Slider
        TableSpeedSliderLabel           matlab.ui.control.Label
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        MovetoAngleButton               matlab.ui.control.Button
        TableNewAngle                   matlab.ui.control.NumericEditField
        StopTableButton                 matlab.ui.control.Button
        TowerTab                        matlab.ui.container.Tab
        AntTowerGridLayout              matlab.ui.container.GridLayout
        TableSpeedSliderLabel_3         matlab.ui.control.Label
        PhiSingleSweepSwitch            matlab.ui.control.Switch
        TowerSpeedSlider                matlab.ui.control.Slider
        TowerSpeedSliderLabel           matlab.ui.control.Label
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        MoveTowertoAngleButton          matlab.ui.control.Button
        TowerNewAngle                   matlab.ui.control.NumericEditField
        StopTowerButton                 matlab.ui.control.Button
        LinearSliderTab                 matlab.ui.container.Tab
        AntSliderGridLayout             matlab.ui.container.GridLayout
        SliderScan                      matlab.ui.control.Button
        SliderHoming                    matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        StopLinearSliderButton          matlab.ui.control.Button
        SpeedPresetDropDown             matlab.ui.control.DropDown
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingLabel             matlab.ui.control.Label
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        SliderPositioncmLabel           matlab.ui.control.Label
        AntennaPhysicalSize             matlab.ui.control.NumericEditField
        AntennaOffsetLabel              matlab.ui.control.Label
        AntennaPattern                  matlab.ui.container.TabGroup
        AntennaGainTab                  matlab.ui.container.Tab
        AntGainGridLayout               matlab.ui.container.GridLayout
        BrowseReferenceFileButton       matlab.ui.control.Button
        ReferenceGainFileField          matlab.ui.control.EditField
        PlotReferenceGainButton         matlab.ui.control.Button
        GainvsFrequencyBoresightGain    matlab.ui.control.UIAxes
        ReturnLossBoresightGain         matlab.ui.control.UIAxes
        RadiationPatternTab             matlab.ui.container.Tab
        AntPatternGridLayout            matlab.ui.container.GridLayout
        GainvsAngle2D                   matlab.ui.control.UIAxes
        GainvsFrequency2D               matlab.ui.control.UIAxes
        ReturnLoss2D                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %% App Specific Settings
        StoredAddresses = {};
        LINEAR_SLIDER_RANGE = 2    % Maximum linear slider movement value (2 meters)
        
        %% Antenna Properties
        % Measurement Properties
        offsetSpacing = 0.8062;    % Spacing between antennas (m)
        linearSpacing              % Spacing added by linear slider (m)

    end

    properties (Access = public)
        %% Instrument Objects
        PowerSupplyA               % DC Power Supply A 
        PowerSupplyB               % DC Power Supply B
        SignalGenerator            % Signal Generator
        SpectrumAnalyzer           % Spectrum Analyzer
        VNA                        % Vector Network Analyzer
        EMCenter                   % Anechoic Chamber Control  
        LinearSlider               % Anechoic Chamber Linear Slider

        %% Power Amplifier Properties
        ChannelToDeviceMap         % A containers.Map linking logical channel names (e.g., 'A1') to PSU device channels (e.g., 'CH1,PSUA').
        PSUMode                    % Single, Dual, or Quad Supply Mode
        CalibrationMode            % None, Fixed Attenuation, Small Signal, Small + Large Signal 
        TypeFreqMeasurementMode    % Single frequency or sweep frequencies
        ChannelNames               % Struct containing the names of the channels to be used by the PSU.
        FilledPSUChannels          % Cell array containing the names of the configured PSU channels that are ready for use.
        DrainChannels              % Cell array containing the channels designated as drains.
        GateChannels               % Cell array containing the channels designated as gates.
        InputSpFile                % Path for the S2P file for input network de-embedding.
        OutputSpFile               % Path for the S2P file for output network de-embedding.
        DriverFile                 % Path for the driver configuration file containing a power amplifier characterization.
        PAMeasurementsTable        % Table storing the results of various measurements for the power amplifier.

        % Measurement Properties
        PA_DataTable
        PA_PSU_Channels
        PA_PSU_Voltages
        PA_PSU_SelectedVoltages

        %% Antenna Properties
        AntennaStopRequested = false;     % Boolean flag: true if user stopped the antenna test, false otherwise.
        ReferenceGainFile                 % 
        Theta 
        Phi
        Antenna_Frequencies 
        AntennaGain_dBi
        S11_Mag_dB                        % Magnitude of S11 in (dB).
        S11_Phase_deg                     % Phase of S11 in (deg).
        S21_Mag_dB                        % Magnitude of S21 in (dB).
        S21_Phase                         % Phase of S21 in (deg).
        S22_Mag_dB                        % Magnitude of S22 in (dB).
        S22_Phase_deg                     % Phase of S22 in (deg).

        setupSpacing                        % Total spacing between antennas accounting for LS and offset (m)

        angleRange                 % 
        frequencyRange             % 
        selectedAngle              % User selected angle to plot
        selectedFrequency          % User selected frequency to plot 

        RadiationPlot2D
        
    end

    methods (Access = public)
        function displayError(app, ME)
            % This function displays a detailed error message to the GUI 
            % alert box. It takes in an MException object (ME), extracts
            % the error details (message, type, and stack trace), 
            % and formats them into a readable string for the user.
            detailedErrorMessage = sprintf('Error: %s\n\nType of Error: %s\n\nStack Trace:\n', ME.message, ME.identifier);
            for k = 1:length(ME.stack)
                detailedErrorMessage = sprintf('%sIn file: %s\nFunction: %s\nLine: %d\n\n', ...
                    detailedErrorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
            end
            uialert(app.UIFigure, detailedErrorMessage, 'Error', 'Icon', 'warning');
        end
        
        function PSUChangeModeHandle(app)
            % Public handle function to execute callback
            app.PSUChangeMode();
        end
        function PAPlotMeasurementHandle(app)
            % Public handle function to execute callback
            app.PAPlotMeasurement();
        end
    end

    methods (Access = private)
        function resetAntennaMeasurementProperties(app)
            app.Theta = [];
            app.Antenna_Frequencies = [];
            app.S11_Mag_dB = [];
            app.S21_Mag_dB = [];
            app.S22_Mag_dB = [];
            app.AntennaGain_dBi = [];
        end

        function connectInstrument(app, instrumentNames, instrumentResources)
            % This function establishes a connection to a set of instruments using their VISA resource names. It iterates through 
            % the provided instrument names and resources, checks if the instrument is enabled for connection, and attempts to connect 
            % using MATLAB’s visadev function. Upon successful connection, the function updates the UI with the connection status and 
            % device information; if the connection fails, it displays an error message.
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentResources{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                statusLabelField = strcat(instrumentName, 'StatusLabel'); 

                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource) && ~strcmp(instrumentResource, 'NA')
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            app.(instrumentName).ByteOrder = 'little-endian';
                            app.(dropdownFieldName).Enable = 'off';
                            idn = writeread(app.(instrumentName), '*IDN?');

                            % Update status label to show success.
                            if strcmp(instrumentName, 'EMCenter')
                                idnParts = split(idn, ' ');
                                app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{3});
                            else
                                idnParts = split(idn, ",");  
                                app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{2});
                            end
                        catch ME
                            % Update status label to show failure.
                            app.(statusLabelField).Text = "Failed";
                            if strcmp(ME.identifier,'instrument:interface:visa:unableToDetermineInterfaceType')
                                app.(statusLabelField).Text = "Instrument Unavailable";
                                uialert(app.UIFigure, 'Failed to connect. Check the network settings and selected addresses.', 'Error', 'Icon', 'warning');
                            else
                                app.displayError(ME);
                            end
                        end
                    else
                        % Update status label if "None: NA" is selected.
                        app.(statusLabelField).Text = "Not Connected";
                    end
                end
            end
        end


        function disconnectInstrument(app, instrumentNames, instrumentResources)
            for i = 1:numel(instrumentResources)
                if ~isempty(instrumentResources{i})
                    flush(instrumentResources{i});
                    instrumentResources{i} = [];
                end
            end
            % 
            %     if strcmp(app.(dropdownFieldName).Enable, 'on')
            %         if ~isempty(instrumentResource) && ~strcmp(instrumentResource, 'NA')
            %             try
            % 
            %             catch ME
            %                 % Update status label to show failure.
            %                 app.(statusLabelField).Text = "Failed";
            %                 if strcmp(ME.identifier,'instrument:interface:visa:unableToDetermineInterfaceType')
            %                     app.(statusLabelField).Text = "Instrument Unavailable";
            %                     uialert(app.UIFigure, 'Failed to connect. Check the network settings and selected addresses.', 'Error', 'Icon', 'warning');
            %                 else
            %                     app.displayError(ME);
            %                 end
            %             end
            %         else
            %             % Update status label if "None: NA" is selected.
            %             app.(statusLabelField).Text = "Not Connected";
            %         end
            %     end
        end

        function saveInstrumentAddressestoTextFile(~, address, model)
            % Save to the text file containing the lab instruments and 
            % their respective IP addresses.
            fid = fopen('instrument_address_history.txt', 'a');
            
            % If the user provided the instrument model, we save it along 
            % with the IP address.
            if nargin > 2 && ~isempty(model)
                fprintf(fid, '%% Model: %s\n', model);
            end
            
            % Save the IP address.
            fprintf(fid, '%% Address: %s\n', address);
            fclose(fid);
        end


        function loadInstrumentAddressestoTextFile(app)
            [appPath, ~, ~] = fileparts(mfilename('fullpath'));
            filename = fullfile(appPath, 'instrument_address_history.txt');

            % Load from the text file containing the lab instruments and 
            % their respective IP addresses.
            if isfile(filename)
                fileContent = fileread(filename);
                lines = strsplit(fileContent, '\n');
 
                addresses = {};
                displayNames = {};
                currentModel = '';
                
                for i = 1:length(lines)
                    line = strtrim(lines{i});
                    
                    if isempty(line)
                        continue;
                    end
                    
                    % Check if this is the line containing the instrument 
                    % model.
                    if startsWith(line, '% Model:')
                        currentModel = strtrim(extractAfter(line, '% Model:'));
                    % Check if this is the line containing the instrument
                    % address.
                    elseif startsWith(line, '% Address:')
                        address = strtrim(extractAfter(line, '% Address:'));
                        addresses{end+1} = address;
                        
                        % Create display name with model and address. 
                        % Model: SMW200A TCPIP0::192.168.3.52::hislip0::INSTR
                        if ~isempty(currentModel)
                            displayNames{end+1} = sprintf('%s: %s', currentModel, address);
                        else
                            displayNames{end+1} = address;
                        end
                        
                        currentModel = '';
                    end
                end

                % Update the app's instrument dropdowns.
                Dropdowns = {app.PowerSupplyADropDown, app.PowerSupplyBDropDown, app.SpectrumAnalyzerDropDown, app.SignalGeneratorDropDown, app.VNADropDown, app.EMCenterDropDown};
                for idx = 1:numel(Dropdowns)
                    currentDropdown = Dropdowns{idx};
                    currentDropdown.Items = displayNames;
                end
            else
                app.StoredAddresses = {};
            end
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application startup settings.
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            app.loadInstrumentAddressestoTextFile();
            
            % Add all relevant Application support functions to the path.
            addpath(genpath('.\'))
            
            % Handle PA specific settings.
            app.PSUMode = 'Single Supply';
            PAChangeCalibrationMode(app);
            PAChangeFrequencyMeasurementMode(app);
            app.ChannelNames = struct();
            app.ChannelToDeviceMap = containers.Map({'A1', 'A2', 'B1', 'B2'}, {'CH1,PSUA', 'CH2,PSUA', 'CH1,PSUB', 'CH2,PSUB'});
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrentValueField.Visible = 'on';
            app.SupplyVoltageStartValueField.Visible = 'on';
            app.SupplyVoltageStopValueField.Visible = 'off';
            app.SupplyVoltageStepValueField.Visible = 'off';

            % Handle Antenna specific settings.
            app.RadiationPlot2D = polaraxes(app.AntPatternGridLayout);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  
            app.RadiationPlot2D.Title.String = '2D Radiation Pattern';
            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';
        end

        % Value changed function: PowerSupplyADropDown, 
        % ...and 3 other components
        function PAInstrumentsAddressChanged(app, event)
            % When a new address is entered into the instrument address 
            % dropdown menu, this callback:
            % 1. Adds the address to the stored addresses list.
            % 2. Updates all instrument menus with the new address.
            % 3. Saves the updated address list to a text file outside the application.
            % 4. If an address is chosen from the menu (already valid
            % address) the function will return.
      
            Input = event.Source.Value;
            pattern = '^([A-Za-z0-9\-]+):\s*((TCPIP0::[\d\.]+::[\w\-]+::[\w\-]+)|(GPIB\d+::\d+::INSTR))$';
    
            % Validation check for addresses loaded into the app. 
            if ~isempty(regexp(Input, pattern, 'once')) || strcmp(Input, 'None: NA')
                return;
            end

            try        
                % Validate that the input starts with "Model:".
                if ~startsWith(Input, 'Model:', 'IgnoreCase', true)
                    error('Invalid format. The input must start with "Model:"');
                end
                
                pattern = '^Model:\s+(\S+)\s+(.*)';
                tokens = regexp(Input, pattern, 'tokens');
                if isempty(tokens)
                    error('Invalid input format. Ensure the format is "Model: <model> <address>"');
                end
                
                % Extract the model and the address from regex tokens.
                model = tokens{1}{1};
                address = strtrim(tokens{1}{2});
                
                % Check if model and address are valid.
                if isempty(model)
                    error('No model specified after "Model:".');
                end
                if isempty(address)
                    error('No address specified after the model name.');
                end
                
                if ~ismember(address, app.StoredAddresses)
                    app.StoredAddresses{end+1} = address;
                    
                    % Update the app's instrument dropdowns.
                    Dropdowns = {app.PowerSupplyADropDown, app.PowerSupplyBDropDown, app.SpectrumAnalyzerDropDown, app.SignalGeneratorDropDown, app.VNADropDown, app.EMCenterDropDown};
                    for idx = 1:numel(Dropdowns)
                        currentDropdown = Dropdowns{idx};
                        if ~ismember(address, currentDropdown.Items)
                            currentDropdown.Items{end+1} = address;
                        end
                    end
                    
                    % Save the new address and model to the text file.
                    saveInstrumentAddressestoTextFile(app, address, model);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupplyA', 'PowerSupplyB', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentResources = {app.PowerSupplyADropDown.Value,app.PowerSupplyBDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};
            
            pattern = '(TCP|USB|GPIB)[^ ]*';

            for i = 1:length(instrumentResources)
                instrumentResources{i} = regexp(instrumentResources{i}, pattern, 'match', 'once');
            end
            
            app.connectInstrument(instrumentNames, instrumentResources);
        end

        % Button pushed function: PADisconnectButton
        function PADisconnectInstruments(app, event)
            instrumentConnections = {app.PowerSupplyA, app.PowerSupplyB, app.SignalGenerator, app.SpectrumAnalyzer};
            app.disconnectInstrument(instrumentConnections);
        end

        % Value changed function: PowerSupplyModeDropDown
        function PSUChangeMode(app, event)
            newMode = app.PowerSupplyModeDropDown.Value; 

            % The default value of app.PSUMode is 'Single Supply' for
            % safety reasons.
            if isempty(newMode)
                app.PSUMode = 'Single Supply';
            else
                app.PSUMode = newMode;
            end

            % If the mode changes to 'No Supply' all input parameters to
            % the PSU are disabled to the user.
            switch newMode
                case 'No Supply'
                    app.SupplyCurrentValueField.Enable = 'off';
                    app.SupplyVoltageStartValueField.Enable = 'off';
                    app.SupplyVoltageStopValueField.Enable = 'off';
                    app.SupplyVoltageStepValueField.Enable = 'off';
                otherwise
                    app.SupplyCurrentValueField.Enable = 'on';
                    app.SupplyVoltageStartValueField.Enable = 'on';
                    app.SupplyVoltageStopValueField.Enable = 'on';
                    app.SupplyVoltageStepValueField.Enable = 'on';
            end

            app.PSUChannelDropDownOpening();
        end

        % Value changed function: PowerSupplyChannelDropDown
        function PSUChangeChannel(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel) || ~ischar(selectedChannel)
                return;
            end

            % If the channel is new, initialize its values
            if ~isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel) = struct('Current', 0, 'Start', 0, 'Stop', 0, 'Step', 0, 'Mode', 'Single');

                % New channel is drain by default
                if ~ismember(selectedChannel, [app.DrainChannels, app.GateChannels])
                    app.DrainChannels{end+1} = selectedChannel;
                    app.DrainGateSwitch.Value = 'Drain';
                end
            else
                % Set the mode switch based on saved channel mode
                if isfield(app.ChannelNames.(selectedChannel), 'Mode')
                    app.SingleSweepSwitch.Value = app.ChannelNames.(selectedChannel).Mode;
                else
                    app.SingleSweepSwitch.Value = 'Single';  % Default to single if mode not set
                    app.ChannelNames.(selectedChannel).Mode = 'Single';
                end

                % Update DrainGateSwitch based on channel designation
                if any(strcmp(selectedChannel, app.DrainChannels))
                    app.DrainGateSwitch.Value = 'Drain';
                elseif any(strcmp(selectedChannel, app.GateChannels))
                    app.DrainGateSwitch.Value = 'Gate';
                else
                    % Default to Drain if not in either list
                    app.DrainGateSwitch.Value = 'Drain';
                    app.DrainChannels{end+1} = selectedChannel;
                end

                % Call the SingleSweepSwitch function to update UI visibility
                PSUChangeSingleSweepVoltages(app, []);
            end

            % Load saved values into UI edit fields
            app.SupplyCurrentValueField.Value = app.ChannelNames.(selectedChannel).Current;
            app.SupplyVoltageStartValueField.Value = app.ChannelNames.(selectedChannel).Start;
            app.SupplyVoltageStopValueField.Value = app.ChannelNames.(selectedChannel).Stop;
            app.SupplyVoltageStepValueField.Value = app.ChannelNames.(selectedChannel).Step;
        end

        % Drop down opening function: PowerSupplyChannelDropDown
        function PSUChannelDropDownOpening(app, event)
            % Check which PSUs are connected
            hasPSUA = ~isempty(app.PowerSupplyA);
            hasPSUB = ~isempty(app.PowerSupplyB);

            if ~hasPSUA && ~hasPSUB
                items = {''};
                app.PowerSupplyChannelDropDown.Items = items;
                return;
            end

            items = {};
            if hasPSUA
                items = [items {'A1', 'A2'}];
            end
            if hasPSUB
                items = [items {'B1', 'B2'}];
            end

            currentValue = app.PowerSupplyChannelDropDown.Value;
            
            % Populate power supply dropdown with items
            app.PowerSupplyChannelDropDown.Items = items;
            
            % Make sure there's at least one item and set to first item if current is invalid
            if ~isempty(items)
                if isempty(currentValue) || ~ismember(currentValue, items)
                    app.PowerSupplyChannelDropDown.Value = items{1};
                else
                    app.PowerSupplyChannelDropDown.Value = currentValue;
                end
                % Change the channel if there is a valid selection
                if ~isempty(app.PowerSupplyChannelDropDown.Value)
                    PSUChangeChannel(app, []);
                end
            end
        end

        % Value changed function: SupplyCurrentValueField
        function PSUSetLimitingCurrent(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Current = app.SupplyCurrentValueField.Value;
            end 
            populatePSUChannels(app);        
        end

        % Value changed function: SupplyVoltageStartValueField
        function PSUSetStartVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Start = app.SupplyVoltageStartValueField.Value;
            end   
            populatePSUChannels(app); 
        end

        % Value changed function: SupplyVoltageStopValueField
        function PSUSetStopVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Stop = app.SupplyVoltageStopValueField.Value;
            end 
            populatePSUChannels(app); 
        end

        % Value changed function: SupplyVoltageStepValueField
        function PSUSetStepVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Step = app.SupplyVoltageStepValueField.Value;
            end 
            populatePSUChannels(app);
        end

        % Value changed function: SingleSweepSwitch
        function PSUChangeSingleSweepVoltages(app, event)
            channelFunction = app.SingleSweepSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            % Update GUI based on single PSU value or sweep PSU values.
            if strcmp(channelFunction, 'Single')
                app.SupplyCurrentValueField.Visible = 'on';
                app.SupplyVoltageStartValueField.Visible = 'on';
                app.SupplyVoltageStopValueField.Visible = 'off';
                app.SupplyVoltageStepValueField.Visible = 'off';
            elseif strcmp(channelFunction, 'Sweep')
                app.SupplyCurrentValueField.Visible = 'on';
                app.SupplyVoltageStartValueField.Visible = 'on';
                app.SupplyVoltageStopValueField.Visible = 'on';
                app.SupplyVoltageStepValueField.Visible = 'on';
            end
            
            % Store the mode selection into the channel configuration.
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Mode = channelFunction;
            end
        end

        % Value changed function: DrainGateSwitch
        function PSUChangeDrainGate(app, event)
            channelDesignation = app.DrainGateSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            if strcmp(channelDesignation, 'Drain')
                app.DrainChannels = unique([app.DrainChannels, {selectedChannel}]);
                app.GateChannels = setdiff(app.GateChannels, {selectedChannel});
            elseif strcmp(channelDesignation, 'Gate')
                app.GateChannels = unique([app.GateChannels, {selectedChannel}]);
                app.DrainChannels = setdiff(app.DrainChannels, {selectedChannel});
            end
        end

        % Value changed function: MeasurementTypeDropDown
        function PAChangeFrequencyMeasurementMode(app, event)
            newType = app.MeasurementTypeDropDown.Value;
            
            % The default value of app.TypeFreqMeasurementMode is
            % 'Single Frequency' for safety reasons.
            if isempty(newType)
                app.TypeFreqMeasurementMode = 'Single Frequency';
            else
                app.TypeFreqMeasurementMode = newType;
            end

            if strcmp(app.TypeFreqMeasurementMode, 'Sweep Frequencies')
                app.EndFrequency.Enable = 'on';
                app.FrequencyStep.Enable = 'on';
            elseif strcmp(app.TypeFreqMeasurementMode, 'Single Frequency')
                app.EndFrequency.Enable = 'off';
                app.FrequencyStep.Enable = 'off';
            end
        end

        % Value changed function: CalibrationModeDropDown
        function PAChangeCalibrationMode(app, event)
            newMode = app.CalibrationModeDropDown.Value;

            % The default value of app.calibrationMode is 'None' for
            % safety reasons.
            if isempty(newMode)
                app.CalibrationMode = 'None';
            else
                app.CalibrationMode = newMode;
            end

            switch app.CalibrationMode
                case 'None'
                    app.InputAttenuationValueField.Enable = 'off';
                    app.OutputAttenuationValueField.Enable = 'off';
                    app.LoadInputSmallSignalButton.Enable = 'off';
                    app.LoadOuputSmallSignalButton.Enable = 'off';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';

                    app.InputAttenuationValueField.Value = 0;
                    app.OutputAttenuationValueField.Value = 0;
                case 'Fixed Attenuation'
                    app.InputAttenuationValueField.Enable = 'on';
                    app.OutputAttenuationValueField.Enable = 'on';
                    app.LoadInputSmallSignalButton.Enable = 'off';
                    app.LoadOuputSmallSignalButton.Enable = 'off';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Small Signal'
                    app.InputAttenuationValueField.Enable = 'on';
                    app.OutputAttenuationValueField.Enable = 'on';
                    app.LoadInputSmallSignalButton.Enable = 'on';
                    app.LoadOuputSmallSignalButton.Enable = 'on';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Small + Large Signal'
                    app.InputAttenuationValueField.Enable = 'off';
                    app.OutputAttenuationValueField.Enable = 'off';
                    app.LoadInputSmallSignalButton.Enable = 'on';
                    app.LoadOuputSmallSignalButton.Enable = 'on';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadInputSmallSignalButton
        function LoadSmallSignalInputS2PFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile('*.s2p', 'Select Input S-Parameter File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % The app expects the S2P file to be in linear magnitude
                % format so we read the file and check the file header.
                fid = fopen(filePath, 'r');
                isLinMagFormat = false;

                for i = 1:20
                    if ~feof(fid)
                        line = fgetl(fid);
                        cleanLine = regexprep(line, '\s+', '');

                        if contains(upper(cleanLine), '#HZSMAR')
                            isLinMagFormat = true;
                            break;
                        end
                    else
                        break
                    end
                end
                fclose(fid);

                % For verification purposes we call sparameters().
                testFile = sparameters(filePath);

                % If the file is in dB format, then we convert it.
                if ~isLinMagFormat
                    % Creates a new filename to let the user know this is 
                    % the linear magnitude version. 
                    [~, basename] = fileparts(file);
                    linearFileName = fullfile(path, [basename '_linear.s2p']);

                    % With rfwrite we modify the format from 'DB' to 'MA'.
                    rfwrite(testFile, linearFileName, 'FrequencyUnit', 'Hz', 'Format', 'MA');
 
                    filePath = linearFileName;
                    file = [basename '_linear.s2p'];
                end

                % Store the file path in the app as a property.
                app.InputSpFile = filePath;

                % Display the selected or converted filename in the app.
                app.InputSmallSignalFilenameField.Value = file;

            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: LoadOuputSmallSignalButton
        function LoadSmallSignalOutputS2PFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile('*.s2p', 'Select Output S-Parameter File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % The app expects the S2P file to be in linear magnitude
                % format so we read the file and check the file header.
                fid = fopen(filePath, 'r');
                isLinMagFormat = false;

                for i = 1:20
                    if ~feof(fid)
                        line = fgetl(fid);
                        cleanLine = regexprep(line, '\s+', '');

                        if contains(upper(cleanLine), '#HZSMAR')
                            isLinMagFormat = true;
                            break;
                        end
                    else
                        break
                    end
                end
                fclose(fid);

                % For verification purposes we call sparameters().
                testFile = sparameters(filePath);

                % If the file is in dB format, then we convert it.
                if ~isLinMagFormat
                    % Creates a new filename to let the user know this is 
                    % the linear magnitude version. 
                    [~, basename] = fileparts(file);
                    linearFileName = fullfile(path, [basename '_linear.s2p']);

                    % With rfwrite we modify the format from 'DB' to 'MA'.
                    rfwrite(testFile, linearFileName, 'FrequencyUnit', 'Hz', 'Format', 'MA');
 
                    filePath = linearFileName;
                    file = [basename '_linear.s2p'];
                end

                % Store the file path in the app as a property.
                app.OutputSpFile = filePath;
                % Display the selected filename in the app.
                app.OutputSmallSignalFilenameField.Value = file;
            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: LoadLargeSignalDriverResponseButton
        function LoadLargeSignalDriverResponseFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile({'*.xlsx;*.xls;*.csv', 'Data Files (*.xlsx, *.xls, *.csv)'}, 'Select Driver File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % For verification purposes we call readtable().
                driverData = readtable(filePath, 'VariableNamingRule', 'preserve');

                requiredColumns = {'Frequency (MHz)', 'RF Input Power (dBm)', 'Gain'};
                for i = 1:length(requiredColumns)
                    if ~any(strcmp(driverData.Properties.VariableNames, requiredColumns{i}))
                        error('Missing required column: %s', requiredColumns{i});
                    end
                end

                app.DriverFile = filePath;

                app.LargeSignalDriverResponseFilenameField.Value = file;
            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            % Validate the channel configuration first.
            if ~validatePSUChannels(app)
                return;
            end

            runPAMeasurement(app);
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            try
                plotPASingleMeasurement(app);
                plotPASweepMeasurement(app);
            catch ME
                uialert(app.UIFigure, 'Failed to plot data.', 'Error', 'Icon', 'warning');
            end
        end

        % Callback function
        function PASaveData(app, event)
            if strcmp(app.TypeFreqMeasurementMode, 'Sweep Frequencies')
                numFreq = length(app.inputFrequencySweep);
                maxLength = max(length(app.inputPdBmSweep));
                paddedFreq = NaN(maxLength, numFreq);
                for i = 1:numFreq
                    paddedFreq(1,i) = app.inputFrequencySweep(i);
                end
                transpose(paddedFreq);
    
                combinedData = [paddedFreq, app.inputPdBmSweep', app.RFOutputPdBm', app.DCDrainPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = cell(1, size(combinedData, 2));
                parameterNames = {'Frequency (Hz)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
    
                for i = 1:numFreq
                    combinedNames{i} = sprintf('%s [%d]', parameterNames{1}, i);
                end
    
                combinedNames{numFreq + 1} = 'Input Power (dBm)';
    
                index = numFreq + 2;
    
                for k = 2:numel(parameterNames)
                    for j = 1:numFreq
                        combinedNames{index} = sprintf('%s [%d]', parameterNames{k}, j);
                        index = index + 1;
                    end
                end
            elseif strcmp(app.TypeFreqMeasurementMode, 'Single Frequency')
                maxLength = max(length(app.inputPdBmSingle));
                paddedFreq = nan(maxLength, 1);
                paddedFreq(1:length(app.inputFrequencySingle)) = app.inputFrequencySingle;
                transpose(paddedFreq)
                
                combinedData = [paddedFreq, app.inputPdBmSingle', app.RFOutputPdBm', app.DCDrainPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
            end

            try
                saveData(combinedData, combinedNames);
            catch ME
                 uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end 
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                app.PA_DataTable = loadData('PA');
                if ~isempty(app.PA_DataTable)                   
                    % Find PSU channel numbers
                    varNames = app.PA_DataTable.Properties.VariableNames;
                    matches = regexp(varNames, '^Channel(\d+)VoltagesV$', 'tokens');

                    % Flatten the list and convert to numeric
                    app.PA_PSU_Channels = cellfun(@(x) str2double(x{1}), matches(~cellfun('isempty', matches)));

                    % Get the voltages for each PSU
                    app.PA_PSU_SelectedVoltages = zeros(numel(app.PA_PSU_Channels),1);
                    app.PA_PSU_Voltages = struct();
                    for i = 1:numel(app.PA_PSU_Channels)
                        chNum = app.PA_PSU_Channels(i);
                        chName = sprintf('Channel%dVoltagesV', chNum);
                        app.PA_PSU_Voltages.(chName) = unique(app.PA_DataTable.(chName));
                        app.PA_PSU_SelectedVoltages(chNum) = app.PA_PSU_Voltages.(chName)(1);
                    end

                    app.PSUChannelDropDown.Items = string(app.PA_PSU_Channels);
                    app.PSUChannelDropDown.Value = app.PSUChannelDropDown.Items(1);
                    app.ChannelVoltageDropDown.Items = string(app.PA_PSU_Voltages.(sprintf('Channel%dVoltagesV', str2double(app.PSUChannelDropDown.Value))));
                    app.ChannelVoltageDropDown.Value = string(app.PA_PSU_SelectedVoltages(str2double(string(app.PSUChannelDropDown.Value))));
                    app.FrequencySingleDropDown.Items = string(unique(app.PA_DataTable.FrequencyMHz));
                    app.FrequencySingleDropDown.Value = app.FrequencySingleDropDown.Items(1);

                    app.PAPlotMeasurementHandle();
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % This function handles the connection of the application to the antenna measurement instruments
            % (Vector Network Analyzer (VNA) and EMCenter). The user selects the appropriate instrument addresses via a dropdown menu, 
            % and the function uses these selections to establish connections. If successful, the instruments are ready for subsequent 
            % commands and data acquisition.      
            
            %instrumentResources = {'TCPIP0::192.168.1.113::hislip0::INSTR', 'TCPIP0::192.168.2.150::inst0::INSTR'}; 
            instrumentNames = {'VNA', 'EMCenter'};
            instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value};
            
            pattern = '(TCP|USB)[^ ]*';
            
            for i = 1:length(instrumentResources)
                instrumentResources{i} = regexp(instrumentResources{i}, pattern, 'match', 'once');
            end
            
            app.connectInstrument(instrumentNames, instrumentResources);
        end

        % Button pushed function: AntennaDisconnectButton
        function AntennaDisconnectInstruments(app, event)
            instrumentConnections = {app.VNA, app.EMCenter, app.LinearSlider};
            app.connectInstrument(instrumentConnections);
        end

        % Button pushed function: ConnectButton
        function LinearSliderConnect(app, event)
            % This function establishes a connection between the application and the linear slider hardware via TCP/IP. It ensures that 
            % a connection is made only if none exists already, it retrieves the current slider position, and updates the relevant GUI components
            % to reflect the connection status and enable user interactions.

            if isempty(app.LinearSlider)
                try 
                    % Initialize TCP client connection
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';

                    % Get current position from Linear Slider
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = app.LINEAR_SLIDER_RANGE + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.setupSpacing = app.AntennaCurrentSpacing.Value;

                    % Enable Linear Slider GUI interface
                    app.AntennaCurrentSpacing.Enable = 'on';
                    app.LinearSliderCurrentPosition.Enable = 'on';
                    app.SpeedPresetDropDown.Enable = 'on';
                    app.MovetoPositionButton.Enable = 'on';
                    app.LinearSliderNewPosition.Enable = 'on';
                catch ME
                    app.displayError(ME);
                end
            end
        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderChangeSpeed(app, event)
            % This function updates the movement speed of the linear slider by sending a speed preset command to the hardware. The speed 
            % settings are predefined as presets, with values ranging from 1 (slowest) to 8 (fastest). The selected speed preset is read 
            % from the application's dropdown menu and applied directly to the slider.
            
            try 
                % Send command to linear slider to change its speed
                speedPreset = str2double(app.SpeedPresetDropDown.Value);
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speedPreset));
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderChangePosition(app, event)
            % This function updates the position the linear slider and dynamically adjusts associated parameters based on the slider's movement. 
            % It continuously monitors the slider's status to ensure the desired position is reached and updates the application's calculated 
            % spacing values in real-time.
            
            try
                % Send command to linear slider to move to the specified position
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                
                % Continuously update the setup spacing until motion is complete
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    
                    sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters
                    baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
                    totalSpacing = baseSpacing + app.offsetSpacing;
                    
                    % Subtract antenna physical size if provided
                    if ~isempty(app.AntennaPhysicalSize.Value)
                        totalSpacing = totalSpacing - app.AntennaPhysicalSize.Value / 100; % Convert to meters
                    end
                    
                    % Update the displayed spacing
                    app.AntennaCurrentSpacing.Value = totalSpacing;
                    
                    % Optional: add a small delay to prevent overwhelming the system
                    pause(0.1);
                end
        
                app.setupSpacing = app.AntennaCurrentSpacing.Value;
                disp(['Final setup spacing: ', num2str(app.setupSpacing), ' meters']);
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopLinearSliderButton
        function LinearSliderStopMovement(app, event)
            % This function sends a stop command to halt the movement of the linear slider. It is triggered 
            % when the user presses the stop button in the linear slider settings menu. 

            try
                writeline(app.LinearSlider, 'AXIS1:STOP')
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: AntennaPhysicalSize
        function LinearSliderApplyAntennaPhysicalOffset(app, event)
            AntennaPhysicalSizeOffset = app.AntennaPhysicalSize.Value;
    
            % Recalculate the current spacing
            sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters
            baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
            totalSpacing = baseSpacing + app.offsetSpacing;
            
            % Subtract antenna physical size offset
            if ~isempty(AntennaPhysicalSizeOffset)
                totalSpacing = totalSpacing - AntennaPhysicalSizeOffset / 100; % Convert to meters
            end
            
            % Update the displayed spacing
            app.AntennaCurrentSpacing.Value = totalSpacing;
            
            % Update the setup spacing
            app.setupSpacing = totalSpacing;
        end

        % Value changed function: TableSpeedSlider
        function TableChangeSpeed(app, event)
           % This function updates the turn-table speed value in the application when the user adjusts the TableSpeedSlider. While this 
           % function does not directly update the speed of the hardware, it ensures that the application's internal settings reflect the new speed. The updated value will be used in subsequent operations involving the turntable, allowing the user to adjust the speed without immediate hardware changes.
 
            try
                app.TableSpeedSlider.Value = app.TableSpeedSlider.Value; 
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoAngleButton
        function TableChangeAngle(app, event)
            % This function initiates the process of changing the turn-table angle to a new value. The function sends a command 
            % to set the turn-table's speed and a command to adjust the turn-table's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so.  
            
            try
                app.AntennaStopRequested = false;
                writeline(app.EMCenter, sprintf('1A:SPEED %d', app.TableSpeedSlider.Value));
                writeline(app.EMCenter, sprintf('1A:SK %d', app.TableNewAngle.Value));
                status = writeread(app.EMCenter,"1A:*OPC?");
                status = str2double(strtrim(status));

                while status ~= 1
                    drawnow;

                    if app.AntennaStopRequested
                        break;
                    end

                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTableButton
        function TableStopMovement(app, event)
            % This function is executed when the user requests to stop the turn-table movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the table to halt any ongoing operation. 
            
            app.AntennaStopRequested = true;
            disp('User stopped turn-table movement.');
            
            try
                writeline(app.EMCenter, '1A:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MoveTowertoAngleButton
        function TowerChangeAngle(app, event)
            % This function initiates the process of changing the tower's angle to a new value. The function sends a command 
            % to set the tower's speed to a default value of 20 and a command to adjust the tower's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so. 
            
            try
                app.AntennaStopRequested = false;
                writeline(app.EMCenter, '1B:SPEED 20');
                writeline(app.EMCenter, sprintf('1B:SK %d', app.TowerNewAngle.Value));
            
                while true                    
                    drawnow;
                    
                    if app.AntennaStopRequested
                        break;
                    end

                    pause(0.1);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTowerButton
        function TowerStopMovement(app, event)
            % This function is executed when the user requests to stop the tower movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the tower to halt any ongoing operation. 
            
            app.AntennaStopRequested = true;
            disp('User stopped tower movement.');

            try
                writeline(app.EMCenter, '1B:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)
            % This function validates the user inputs for the VNA measurement settings and performs the selected measurement 
            % type (either "Gain Characterization" or "Pattern Measurement"). It ensures that the start and end frequencies are set and distinct, 
            % the number of sweep points is specified, and the antenna physical size is provided. Based on the chosen measurement type, the 
            % function either calls the measureBoresightGain method for gain characterization or the get2DAntennaGain method for 2D antenna pattern measurement. 
            
            % Validate the antenna setup configuration first.
            if ~validateAntennaMeasurement(app)
                return;
            end  

            try
                switch app.VNAMeasurement.Value
                    case 'Gain Characterization'
                        measureBoresightGain(app);
                    case 'Pattern Measurement'
                        get2DAntennaGain(app);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTestButton
        function AntennaStopMeasurement(app, event)
            % This function handles the user's request to stop the ongoing 2D radiation pattern measurement. When the stop request is triggered,
            % the function terminates the measurement process, checks if any data has been recorded, and prompts the user to decide whether to save 
            % the collected data. If the user opts to save, the function prepares the data and saves it. 

            app.AntennaStopRequested = true;
            disp('User stopped the test. Terminating measurement and saving data if necessary.');
            
            try
                writeline(app.EMCenter, '1A:ST');
                pause(1);

                % Check if any data has been recorded thus far.
                if ~isempty(app.Theta) && any(app.Theta)
                    % Identify valid data points since the arrays were
                    % initialized with 0's.
                    validIndices = app.Antenna_Frequencies > 0;
                
                    % Filter data arrays
                    filteredAzimuthAngles = app.Theta(validIndices);
                    filteredFrequencies = app.Antenna_Frequencies(validIndices);
                    filteredAntennaGain = app.AntennaGain_dBi(validIndices);
                    filteredReturnLoss_Mag = app.S11_Mag_dB(validIndices);
                    filteredReturnLoss_Phase = app.S11_Phase_deg(validIndices);
                
                    % Combine filtered data
                    combinedData = [double(filteredAzimuthAngles)', double(filteredFrequencies / 1E6)', double(filteredAntennaGain)', double(filteredReturnLoss_Mag)', double(filteredReturnLoss_Phase)'];
                
                    % Handle the case where no valid data exists
                    if isempty(combinedData)
                        disp('No valid data was recorded. Skipping save operation.');
                        return;
                    end
                
                    % Ask the user if they want to save the data
                    question = 'Do you want to save collected data?';
                    choice = uiconfirm(app.UIFigure, question, 'Stop Test', 'Options', {'Yes', 'No'}, 'DefaultOption', 1);
                
                    if strcmp(choice, 'Yes')
                        % Prepare data for the saveData function
                        combinedNames = {'Azimuth Angles (deg)', 'Frequency (MHz)', 'Gain (dBi)', 'Return Loss (dB)', 'Return Loss (deg)'};
                        saveData(combinedData, combinedNames);
                    end
                end
                
                % Reset antenna measurement properties for efficient data
                % handling.
                app.resetAntennaMeasurementProperties();
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotResultsButton
        function AntennaPlotMeasurement(app, event)
            % This function is responsible for plotting the 2D antenna gain measurement results, providing 
            % the user with visual insights into the measurement data. 

            plotAntenna2DMeasurement(app, length(app.Antenna_Frequencies));
        end

        % Button pushed function: LoadResultsButton
        function AntennaLoadData(app, event)
            % This function is responsible for loading an antenna gain file into the application. 
            % The file should adhere to the application's predefined column headers for antenna gain data:
            %
            %       Theta (deg)
            %       Phi (deg)
            %       Frequency (MHz)
            %       Gain (dBi) (Gain in dBi)
            %       Return Loss (dB)
            %       Return Loss (deg)
            % 
            % If any fields are missing, an error is raised to inform the user. Upon successful loading, 
            % the data is extracted and used to populate relevant fields within the application, 
            % including dropdown menus for selecting specific angles and frequencies for plotting.

            try 
                % Load the antenna gain file
                combinedAntennaData = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(combinedAntennaData))
                    return;
                end

                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(combinedAntennaData, 'Thetadeg')
                    missingFields{end+1} = 'Theta (deg)';
                end
                if ~isfield(combinedAntennaData, 'Phideg')
                    missingFields{end+1} = 'Phi (deg)';
                end
                if ~isfield(combinedAntennaData, 'FrequencyMHz')
                    missingFields{end+1} = 'Frequency (MHz)';
                end
                if ~isfield(combinedAntennaData, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdeg')
                    missingFields{end+1} = 'Return Loss (deg)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The antenna gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % Extract data from the file and load it into the
                % application if all required fields are found.
                app.Theta = unique(combinedAntennaData.Thetadeg)';
                app.Phi = unique(combinedAntennaData.Phideg)';
                app.Antenna_Frequencies = unique(combinedAntennaData.FrequencyMHz)';
                app.AntennaGain_dBi = combinedAntennaData.GaindBi';
                app.S11_Mag_dB = combinedAntennaData.ReturnLossdB';
                app.S11_Phase_deg = combinedAntennaData.ReturnLossdeg';

                % Populate dropdown menus, with the angles and frequencies
                % from the gain file so user can select which specific
                % angle or frequency to plot.
                app.PlotAngleDropDown.Items = string(app.Theta);
                app.PlotFrequencyGHzDropDown.Items = string(app.Antenna_Frequencies / 1E3);

                % Set default values for the angle and frequency.
                app.selectedAngle = app.PlotAngleDropDown.Items(1);
                app.selectedFrequency = app.PlotFrequencyGHzDropDown.Items(1);

                % Set default values for the angle and frequency ranges, so
                % the plots only display the necessary data. 
                app.angleRange = 0;
                app.frequencyRange = 1;

                % Replot automatically
                plotAntenna2DMeasurement(app, length(app.Antenna_Frequencies));

            catch ME    
                app.displayError(ME);
            end
        end

        % Value changed function: PlotAngleDropDown
        function AntennaSelectedAngleValue(app, event)
            % This function automatically adjusts the angle range for plotting based on the user's selected angle from the dropdown menu.
            % It retrieves the selected angle and then calculates the corresponding index in the list of available angles.
            % The angle range is updated to reflect the index of the selected angle, which is used to determine which data to plot.

            app.selectedAngle = app.PlotAngleDropDown.Value;   
            app.angleRange = find(strcmp(app.PlotAngleDropDown.Items, app.selectedAngle)) - 1;

            % Replot automatically
            plotAntenna2DMeasurement(app, length(app.Antenna_Frequencies));

        end

        % Value changed function: PlotFrequencyGHzDropDown
        function AntennaSelectedFrequencyValue(app, event)
            % This function automatically adjusts the frequency range for plotting based on the user's selected frequency from the dropdown menu.
            % It retrieves the selected frequency and calculates the corresponding index in the list of available frequencies. 
            % The frequency range is updated to reflect the index of the selected frequency, which is used to determine which data to plot.

            app.selectedFrequency = app.PlotFrequencyGHzDropDown.Value;
            app.frequencyRange = find(strcmp(app.PlotFrequencyGHzDropDown.Items, app.selectedFrequency));

            % Replot automatically
            plotAntenna2DMeasurement(app, length(app.Antenna_Frequencies));
        end

        % Button pushed function: BrowseReferenceFileButton
        function LoadReferenceGainFile(app, event)
            % The reference gain file must adhere to the application's specified column headers format:
            %
            %       Theta (deg)
            %       Phi (deg)
            %       Frequency (MHz)
            %       Gain (dBi) (Gain in dBi)
            %       Return Loss (dB)
            %       Return Loss (deg)
            % 
            % The function verifies that all required fields are present in
            % the file, and if any are missing, it raises an error. If the
            % file is valid, the path to the loaded reference gain file is
            % displayed to the user. The user can then plot the data.

            try 
                % Load the reference gain file.
                app.ReferenceGainFile = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(app.ReferenceGainFile))
                    return;
                end
            
                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(combinedAntennaData, 'Thetadeg')
                    missingFields{end+1} = 'Theta (deg)';
                end
                if ~isfield(combinedAntennaData, 'Phideg')
                    missingFields{end+1} = 'Phi (deg)';
                end
                if ~isfield(combinedAntennaData, 'FrequencyMHz')
                    missingFields{end+1} = 'Frequency (MHz)';
                end
                if ~isfield(combinedAntennaData, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdeg')
                    missingFields{end+1} = 'Return Loss (deg)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The reference gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % If the file presents no errors, the file path is loaded
                % into the file, and displayed to the user.
                app.ReferenceGainFileField.Value = evalin('base', 'loadedFilePath');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotReferenceGainButton
        function PlotReferenceGainFile(app, event)
            % This function plots the reference gain data that has been loaded into the application. It extracts the frequency, 
            % antenna gain, and return loss from the reference gain file and displays them on the corresponding plots for the user.

            try
                % Clear current plots.
                cla(app.GainvsFrequencyBoresightGain);
                cla(app.ReturnLossBoresightGain);

                % Extract antenna measurement parameters from the file.
                Frequencies = app.ReferenceGainFile.FrequencyHz / 1E9;
                AntennaGain =  app.ReferenceGainFile.GaindBi;
                ReturnLoss =  app.ReferenceGainFile.ReturnLossdB;

                % Plot the parameters onto the plots.
                plot(app.GainvsFrequencyBoresightGain, Frequencies, AntennaGain);
                plot(app.ReturnLossBoresightGain, Frequencies, ReturnLoss);

                % Improves the plot appeareance, line thickness can be
                % modified.
                improveAxesAppearance(app.GainvsFrequencyBoresightGain, 'LineThickness', 2);
                improveAxesAppearance(app.ReturnLossBoresightGain, 'LineThickness', 2);
            catch ME
                app.displayError(ME);
            end
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function handles the app's closure behavior. When the user attempts to close the app, the function prompts them 
            % with a confirmation dialog. If the user confirms the closure, the function will clean up by closing all active instrument 
            % connections. If the user cancels the closure, the function does nothing and returns to the app.

            InstrumentConnections = {app.PowerSupplyA, app.PowerSupplyB, app.SignalGenerator, app.SpectrumAnalyzer, app.VNA, app.EMCenter, app.LinearSlider};
            question = 'Close ARES App?';
            choice = uiconfirm(app.UIFigure, question, 'Confirm Close', 'Options', {'Yes', 'No'});

            switch choice
                case 'Yes'
                    for i = 1:numel(InstrumentConnections)
                        if ~isempty(InstrumentConnections{i})
                            flush(InstrumentConnections{i});
                            InstrumentConnections{i} = [];
                        end
                    end
                    app.delete
                case 'No'
            end
        end

        % Value changed function: PhiSingleSweepSwitch
        function PhiSingleSweepSwitchValueChanged(app, event)
            mode = app.PhiSingleSweepSwitch.Value;
            if strcmp(mode, 'Sweep')
                app.TowerStepAngle.Enable = "on";
                app.TowerEndAngle.Enable = "on";
            else
                app.TowerStepAngle.Enable = "off";
                app.TowerEndAngle.Enable = "off";
            end
        end

        % Value changed function: ThetaSingleSweepSwitch
        function ThetaSingleSweepSwitchValueChanged(app, event)
            mode = app.ThetaSingleSweepSwitch.Value;
            if strcmp(mode, 'Sweep')
                app.TableStepAngle.Enable = "on";
                app.TableEndAngle.Enable = "on";
            else
                app.TableStepAngle.Enable = "off";
                app.TableEndAngle.Enable = "off";
            end

        end

        % Value changed function: PSUChannelDropDown
        function PSUChannelDropDownValueChanged(app, event)
            ch = str2double(string(app.PSUChannelDropDown.Value));
            app.ChannelVoltageDropDown.Items = string(app.PA_PSU_Voltages.(sprintf('Channel%dVoltagesV', ch)));
            app.ChannelVoltageDropDown.Value = string(app.PA_PSU_SelectedVoltages(ch));
        end

        % Value changed function: ChannelVoltageDropDown
        function ChannelVoltageDropDownValueChanged(app, event)
            ch = str2double(string(app.PSUChannelDropDown.Value));
            app.PA_PSU_SelectedVoltages(ch) = str2double(string(app.ChannelVoltageDropDown.Value)); 
            app.PAPlotMeasurementHandle();
        end

        % Value changed function: FrequencySingleDropDown
        function FrequencySingleDropDownValueChanged(app, event)
            plotPASingleMeasurement(app);
            plotPASweepMeasurement(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.MainGrid);
            app.InfoTab = uitab(app.MainTabs);
            app.InfoTab.Title = 'Info';
            app.InfoGridLayout = uigridlayout(app.InfoTab);
            app.Image = uiimage(app.InfoGridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'support', 'ARES Icon.png');
            app.AppName = uilabel(app.InfoGridLayout);
            app.AppName.Text = 'Automated Radio Evaluation Suite';
            app.Relase = uilabel(app.InfoGridLayout);
            app.Relase.Text = 'V0.9 BETA Relase';
            app.Repository = uihyperlink(app.InfoGridLayout);
            app.Repository.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite';
            app.Repository.Text = 'GitHub Repo';
            app.Author = uilabel(app.InfoGridLayout);
            app.Author.Text = {'Author: Jose Abrahan Bolaños Vargas'; 'Mentor: Alex D. Santiago Vargas'; 'PI: Dimitrios Peroulis'; 'Adaptive Radio Electronics and Sensors (ARES) Group'};
            app.PATutorial = uihyperlink(app.InfoGridLayout);
            app.PATutorial.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#pa-measurement-tutorial';
            app.PATutorial.Text = 'PA Measurement Tutorial';
            app.AntTutorial = uihyperlink(app.InfoGridLayout);
            app.AntTutorial.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#antenna-measurement-tutorial';
            app.AntTutorial.Text = 'Antenna Tutorial';
            app.README = uihyperlink(app.InfoGridLayout);
            app.README.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite/blob/main/README.md';
            app.README.Text = 'README';
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.PAGridLayout = uigridlayout(app.PowerAmplifierTab);
            app.PAResultsTabs = uitabgroup(app.PAGridLayout);
            app.SingleTab = uitab(app.PAResultsTabs);
            app.SingleTab.Title = 'Single';
            app.PAPlotSingleGridLayout = uigridlayout(app.SingleTab);
            app.SingleFrequencyPAPlot = uiaxes(app.PAPlotSingleGridLayout);
            title(app.SingleFrequencyPAPlot, 'PA Performance Metrics')
            xlabel(app.SingleFrequencyPAPlot, 'Output Power (dBm)')
            zlabel(app.SingleFrequencyPAPlot, 'Z')
            app.SweepTab = uitab(app.PAResultsTabs);
            app.SweepTab.Title = 'Sweep';
            app.PAPlotSweepGridLayout = uigridlayout(app.SweepTab);
            app.GainvsOutputPowerPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.GainvsOutputPowerPlot, 'Gain vs. Output Power')
            xlabel(app.GainvsOutputPowerPlot, 'Output Power (dBm)')
            ylabel(app.GainvsOutputPowerPlot, 'Gain (dB)')
            zlabel(app.GainvsOutputPowerPlot, 'Z')
            app.PeakGainPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.PeakGainPlot, 'Peak Gain')
            xlabel(app.PeakGainPlot, 'Frequency (GHz)')
            ylabel(app.PeakGainPlot, 'Gain (dB)')
            zlabel(app.PeakGainPlot, 'Z')
            app.PeakDEPAEPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.PeakDEPAEPlot, 'Peak DE and PAE')
            xlabel(app.PeakDEPAEPlot, 'Frequency (GHz)')
            ylabel(app.PeakDEPAEPlot, 'Efficiency (%)')
            zlabel(app.PeakDEPAEPlot, 'Z')
            app.CompressionPointsPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.CompressionPointsPlot, 'Saturation Power and Compression Points')
            xlabel(app.CompressionPointsPlot, 'Frequency (GHz)')
            ylabel(app.CompressionPointsPlot, 'Output Power (dBm)')
            zlabel(app.CompressionPointsPlot, 'Z')
            app.PATestSettingsPanel = uipanel(app.PAGridLayout);
            app.PATestSettingsPanel.Title = 'Test Settings';
            app.PATestSettingsGridLayout = uigridlayout(app.PATestSettingsPanel);
            app.StartPAMeasurement = uibutton(app.PATestSettingsGridLayout, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.PATestSettingsGridLayout, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.PlotPAData = uibutton(app.PATestSettingsGridLayout, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.PSUChannelDropDownLabel = uilabel(app.PATestSettingsGridLayout);
            app.PSUChannelDropDownLabel.Text = 'PSU Channel';
            app.PSUChannelDropDown = uidropdown(app.PATestSettingsGridLayout);
            app.PSUChannelDropDown.Items = {''};
            app.PSUChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChannelDropDownValueChanged, true);
            app.PSUChannelDropDown.Value = '';
            app.ChannelVoltageDropDownLabel = uilabel(app.PATestSettingsGridLayout);
            app.ChannelVoltageDropDownLabel.Text = 'Channel Voltage';
            app.ChannelVoltageDropDown = uidropdown(app.PATestSettingsGridLayout);
            app.ChannelVoltageDropDown.Items = {''};
            app.ChannelVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelVoltageDropDownValueChanged, true);
            app.ChannelVoltageDropDown.Value = '';
            app.FrequencySingleDropDownLabel = uilabel(app.PATestSettingsGridLayout);
            app.FrequencySingleDropDownLabel.Text = 'Frequency (Single)';
            app.FrequencySingleDropDown = uidropdown(app.PATestSettingsGridLayout);
            app.FrequencySingleDropDown.Items = {''};
            app.FrequencySingleDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencySingleDropDownValueChanged, true);
            app.FrequencySingleDropDown.Value = '';
            app.PASettingsPanel = uitabgroup(app.PAGridLayout);
            app.InstrumentsTab = uitab(app.PASettingsPanel);
            app.InstrumentsTab.Title = 'Instruments';
            app.PAInstrumentGridLayout = uigridlayout(app.InstrumentsTab);
            app.PowerSupplyAStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyAStatusLabel.Text = 'Status:';
            app.PowerSupplyADropDown = uidropdown(app.PAInstrumentGridLayout);
            app.PowerSupplyADropDown.Items = {''};
            app.PowerSupplyADropDown.Editable = 'on';
            app.PowerSupplyADropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.PowerSupplyADropDown.Placeholder = 'Enter address:';
            app.PowerSupplyADropDown.Value = '';
            app.PowerSupplyBStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyBStatusLabel.Text = 'Status:';
            app.PowerSupplyBDropDown = uidropdown(app.PAInstrumentGridLayout);
            app.PowerSupplyBDropDown.Items = {};
            app.PowerSupplyBDropDown.Editable = 'on';
            app.PowerSupplyBDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.PowerSupplyBDropDown.Placeholder = 'Enter address:';
            app.PowerSupplyBDropDown.Value = {};
            app.SignalGeneratorStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.SignalGeneratorStatusLabel.Text = 'Status:';
            app.SignalGeneratorDropDown = uidropdown(app.PAInstrumentGridLayout);
            app.SignalGeneratorDropDown.Items = {};
            app.SignalGeneratorDropDown.Editable = 'on';
            app.SignalGeneratorDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.SignalGeneratorDropDown.Placeholder = 'Enter address:';
            app.SignalGeneratorDropDown.Value = {};
            app.SpectrumAnalyzerStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.SpectrumAnalyzerStatusLabel.Text = 'Status:';
            app.PowerSupplyALabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyALabel.Text = 'Power Supply A';
            app.PowerSupplyBLabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyBLabel.Text = 'Power Supply B';
            app.SignalGeneratorLabel = uilabel(app.PAInstrumentGridLayout);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.SpectrumAnalyzerDropDown = uidropdown(app.PAInstrumentGridLayout);
            app.SpectrumAnalyzerDropDown.Items = {};
            app.SpectrumAnalyzerDropDown.Editable = 'on';
            app.SpectrumAnalyzerDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.SpectrumAnalyzerDropDown.Placeholder = 'Enter address:';
            app.SpectrumAnalyzerDropDown.Value = {};
            app.PAMeasurementDelayValueField = uieditfield(app.PAInstrumentGridLayout, 'numeric');
            app.PAMeasurementDelayValueField.Limits = [0 Inf];
            app.PAMeasurementDelayValueField.Value = 0.1;
            app.PAConnectButton = uibutton(app.PAInstrumentGridLayout, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.SpectrumAnalyzerLabel = uilabel(app.PAInstrumentGridLayout);
            app.SpectrumAnalyzerLabel.Text = 'Spectrum Analyzer';
            app.MeasurementDelaysEditFieldLabel = uilabel(app.PAInstrumentGridLayout);
            app.MeasurementDelaysEditFieldLabel.Text = 'Measurement Delay (s)';
            app.PADisconnectButton = uibutton(app.PAInstrumentGridLayout, 'push');
            app.PADisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @PADisconnectInstruments, true);
            app.PADisconnectButton.Text = 'Disconnect';
            app.PowerSupplyTab = uitab(app.PASettingsPanel);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.PAPSUGridLayout = uigridlayout(app.PowerSupplyTab);
            app.PowerSupplyModeDropDown = uidropdown(app.PAPSUGridLayout);
            app.PowerSupplyModeDropDown.Items = {'No Supply', 'Single Supply', 'Dual Supply', 'Quad Supply'};
            app.PowerSupplyModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeMode, true);
            app.PowerSupplyModeDropDown.Value = 'Single Supply';
            app.PowerSupplyChannelDropDown = uidropdown(app.PAPSUGridLayout);
            app.PowerSupplyChannelDropDown.Items = {''};
            app.PowerSupplyChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PSUChannelDropDownOpening, true);
            app.PowerSupplyChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeChannel, true);
            app.PowerSupplyChannelDropDown.Value = '';
            app.DrainGateSwitch = uiswitch(app.PAPSUGridLayout, 'slider');
            app.DrainGateSwitch.Items = {'Drain', 'Gate'};
            app.DrainGateSwitch.ValueChangedFcn = createCallbackFcn(app, @PSUChangeDrainGate, true);
            app.DrainGateSwitch.Value = 'Drain';
            app.SingleSweepSwitch = uiswitch(app.PAPSUGridLayout, 'slider');
            app.SingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.SingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @PSUChangeSingleSweepVoltages, true);
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrentValueField = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.SupplyCurrentValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetLimitingCurrent, true);
            app.LimitingCurrentALabel = uilabel(app.PAPSUGridLayout);
            app.LimitingCurrentALabel.Text = 'Current Limit (A)';
            app.SupplyVoltageStartNameField = uilabel(app.PAPSUGridLayout);
            app.SupplyVoltageStartNameField.Text = 'Start Voltage (V)';
            app.SupplyVoltageStartValueField = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.SupplyVoltageStartValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStartVoltage, true);
            app.SupplyVoltageStopEditField = uilabel(app.PAPSUGridLayout);
            app.SupplyVoltageStopEditField.Text = 'Stop Voltage (V)';
            app.SupplyVoltageStopValueField = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.SupplyVoltageStopValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStopVoltage, true);
            app.SupplyVoltageStepNameField = uilabel(app.PAPSUGridLayout);
            app.SupplyVoltageStepNameField.Text = 'Step Voltage (V)';
            app.SupplyVoltageStepValueField = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.SupplyVoltageStepValueField.ValueChangedFcn = createCallbackFcn(app, @PSUSetStepVoltage, true);
            app.SupplyModeLabel = uilabel(app.PAPSUGridLayout);
            app.SupplyModeLabel.Text = 'Supply Mode';
            app.SupplyChannelDropDownLabel = uilabel(app.PAPSUGridLayout);
            app.SupplyChannelDropDownLabel.Text = 'Supply Channel';
            app.SignalAnalysisTab = uitab(app.PASettingsPanel);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.PASAGridLayout = uigridlayout(app.SignalAnalysisTab);
            app.MeasurementTypeDropDown = uidropdown(app.PASAGridLayout);
            app.MeasurementTypeDropDown.Items = {'Single Frequency', 'Sweep Frequencies'};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChangeFrequencyMeasurementMode, true);
            app.MeasurementTypeDropDown.Value = 'Single Frequency';
            app.StartFrequencyMHzLabel = uilabel(app.PASAGridLayout);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.StartFrequency = uieditfield(app.PASAGridLayout, 'numeric');
            app.EndFrequencyMHzLabel = uilabel(app.PASAGridLayout);
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.EndFrequency = uieditfield(app.PASAGridLayout, 'numeric');
            app.FrequencyStepMHzLabel = uilabel(app.PASAGridLayout);
            app.FrequencyStepMHzLabel.Text = 'Frequency Step (MHz)';
            app.FrequencyStep = uieditfield(app.PASAGridLayout, 'numeric');
            app.StartPowerdBmEditFieldLabel = uilabel(app.PASAGridLayout);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.StartPower = uieditfield(app.PASAGridLayout, 'numeric');
            app.EndPowerdBmEditFieldLabel = uilabel(app.PASAGridLayout);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.EndPower = uieditfield(app.PASAGridLayout, 'numeric');
            app.PowerStepdBmEditField = uilabel(app.PASAGridLayout);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.PowerStep = uieditfield(app.PASAGridLayout, 'numeric');
            app.SpanNameField = uilabel(app.PASAGridLayout);
            app.SpanNameField.Text = 'Span (MHz)';
            app.SpanValueField = uieditfield(app.PASAGridLayout, 'numeric');
            app.SpanValueField.Value = 40;
            app.SweepPointsNameField = uilabel(app.PASAGridLayout);
            app.SweepPointsNameField.Text = 'Sweep Points';
            app.SweepPointsValueField = uieditfield(app.PASAGridLayout, 'numeric');
            app.SweepPointsValueField.Limits = [0 Inf];
            app.SweepPointsValueField.Value = 1001;
            app.ReferenceLevelNameField = uilabel(app.PASAGridLayout);
            app.ReferenceLevelNameField.Text = 'Reference Level (dB)';
            app.ReferenceLevelValueField = uieditfield(app.PASAGridLayout, 'numeric');
            app.MeasurementTypeDropDownLabel = uilabel(app.PASAGridLayout);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.SignalGeneratorSettingsLabel = uilabel(app.PASAGridLayout);
            app.SignalGeneratorSettingsLabel.Text = 'Signal Generator Settings';
            app.SpectrumAnalyzerSettingsLabel = uilabel(app.PASAGridLayout);
            app.SpectrumAnalyzerSettingsLabel.Text = 'Spectrum Analyzer Settings';
            app.CalibrationTab = uitab(app.PASettingsPanel);
            app.CalibrationTab.Title = 'Calibration';
            app.PACalGridLayout = uigridlayout(app.CalibrationTab);
            app.CalibrationModeDropDown = uidropdown(app.PACalGridLayout);
            app.CalibrationModeDropDown.Items = {'None', 'Fixed Attenuation', 'Small Signal', 'Small + Large Signal'};
            app.CalibrationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChangeCalibrationMode, true);
            app.CalibrationModeDropDown.Value = 'None';
            app.InputAttenuationValueField = uieditfield(app.PACalGridLayout, 'numeric');
            app.OutputAttenuationValueField = uieditfield(app.PACalGridLayout, 'numeric');
            app.LoadInputSmallSignalButton = uibutton(app.PACalGridLayout, 'push');
            app.LoadInputSmallSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSmallSignalInputS2PFile, true);
            app.LoadInputSmallSignalButton.Text = 'Load Input S-Parameters';
            app.InputSmallSignalFilenameField = uieditfield(app.PACalGridLayout, 'text');
            app.InputSmallSignalFilenameField.Editable = 'off';
            app.InputSmallSignalFilenameField.Placeholder = '.s2p';
            app.LoadOuputSmallSignalButton = uibutton(app.PACalGridLayout, 'push');
            app.LoadOuputSmallSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSmallSignalOutputS2PFile, true);
            app.LoadOuputSmallSignalButton.Text = 'Load Output S-Parameters';
            app.CalibrationModeLabel = uilabel(app.PACalGridLayout);
            app.CalibrationModeLabel.Text = 'Calibration Mode';
            app.InputAttenuationdBLabel = uilabel(app.PACalGridLayout);
            app.InputAttenuationdBLabel.Text = 'Input Attenuation (dB)';
            app.OutputSmallSignalFilenameField = uieditfield(app.PACalGridLayout, 'text');
            app.OutputSmallSignalFilenameField.Editable = 'off';
            app.OutputSmallSignalFilenameField.Placeholder = '.s2p';
            app.LoadLargeSignalDriverResponseButton = uibutton(app.PACalGridLayout, 'push');
            app.LoadLargeSignalDriverResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLargeSignalDriverResponseFile, true);
            app.LoadLargeSignalDriverResponseButton.Text = 'Load Large-Signal Driver Response';
            app.LargeSignalDriverResponseFilenameField = uieditfield(app.PACalGridLayout, 'text');
            app.LargeSignalDriverResponseFilenameField.Editable = 'off';
            app.LargeSignalDriverResponseFilenameField.Placeholder = '.xlsx,.csv';
            app.OutputAttenuationdBEditFieldLabel = uilabel(app.PACalGridLayout);
            app.OutputAttenuationdBEditFieldLabel.Text = 'Output Attenuation (dB)';
            app.SmallSignalFrequencyResponseLabel = uilabel(app.PACalGridLayout);
            app.SmallSignalFrequencyResponseLabel.Text = 'Small-Signal Frequency Response';
            app.DriverResponseLabel = uilabel(app.PACalGridLayout);
            app.DriverResponseLabel.Text = 'Driver Response';
            app.FixedCalibrationLabel = uilabel(app.PACalGridLayout);
            app.FixedCalibrationLabel.Text = 'Fixed Calibration';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.AntGridLayout = uigridlayout(app.AntennaTab);
            app.AntennaPattern = uitabgroup(app.AntGridLayout);
            app.AntennaGainTab = uitab(app.AntennaPattern);
            app.AntennaGainTab.Title = 'Antenna Gain';
            app.AntGainGridLayout = uigridlayout(app.AntennaGainTab);
            app.ReturnLossBoresightGain = uiaxes(app.AntGainGridLayout);
            title(app.ReturnLossBoresightGain, 'Return Loss vs. Frequency')
            xlabel(app.ReturnLossBoresightGain, 'Frequency (GHz)')
            ylabel(app.ReturnLossBoresightGain, 'RL (dB)')
            zlabel(app.ReturnLossBoresightGain, 'Z')
            app.GainvsFrequencyBoresightGain = uiaxes(app.AntGainGridLayout);
            title(app.GainvsFrequencyBoresightGain, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequencyBoresightGain, 'Frequency (GHz)')
            ylabel(app.GainvsFrequencyBoresightGain, 'Gain (dBi)')
            zlabel(app.GainvsFrequencyBoresightGain, 'Z')
            app.PlotReferenceGainButton = uibutton(app.AntGainGridLayout, 'push');
            app.PlotReferenceGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotReferenceGainFile, true);
            app.PlotReferenceGainButton.Text = 'Plot Reference Gain';
            app.ReferenceGainFileField = uieditfield(app.AntGainGridLayout, 'text');
            app.ReferenceGainFileField.Editable = 'off';
            app.BrowseReferenceFileButton = uibutton(app.AntGainGridLayout, 'push');
            app.BrowseReferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceGainFile, true);
            app.BrowseReferenceFileButton.Text = 'Browse Reference File';
            app.RadiationPatternTab = uitab(app.AntennaPattern);
            app.RadiationPatternTab.Title = 'Radiation Pattern';
            app.AntPatternGridLayout = uigridlayout(app.RadiationPatternTab);
            app.ReturnLoss2D = uiaxes(app.AntPatternGridLayout);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)')
            ylabel(app.ReturnLoss2D, 'RL (dB)')
            zlabel(app.ReturnLoss2D, 'Z')
            app.GainvsFrequency2D = uiaxes(app.AntPatternGridLayout);
            title(app.GainvsFrequency2D, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)')
            zlabel(app.GainvsFrequency2D, 'Z')
            app.GainvsAngle2D = uiaxes(app.AntPatternGridLayout);
            title(app.GainvsAngle2D, 'Gain vs. Angle')
            xlabel(app.GainvsAngle2D, 'Angle (°)')
            ylabel(app.GainvsAngle2D, 'Gain (dBi)')
            zlabel(app.GainvsAngle2D, 'Z')
            app.AntennaSettings = uitabgroup(app.AntGridLayout);
            app.AntInstrumentsTab = uitab(app.AntennaSettings);
            app.AntInstrumentsTab.Title = 'Instruments';
            app.AntInstrumentGridLayout = uigridlayout(app.AntInstrumentsTab);
            app.VNALabel = uilabel(app.AntInstrumentGridLayout);
            app.VNALabel.Text = 'VNA';
            app.VNAStatusLabel = uilabel(app.AntInstrumentGridLayout);
            app.VNAStatusLabel.Text = 'Status:';
            app.VNADropDown = uidropdown(app.AntInstrumentGridLayout);
            app.VNADropDown.Items = {};
            app.VNADropDown.Editable = 'on';
            app.VNADropDown.Placeholder = 'Enter address:';
            app.VNADropDown.Value = {};
            app.EMCenterDropDown = uidropdown(app.AntInstrumentGridLayout);
            app.EMCenterDropDown.Items = {};
            app.EMCenterDropDown.Editable = 'on';
            app.EMCenterDropDown.Placeholder = 'Enter address:';
            app.EMCenterDropDown.Value = {};
            app.EMCenterLabel = uilabel(app.AntInstrumentGridLayout);
            app.EMCenterLabel.Text = 'EMCenter';
            app.EMCenterStatusLabel = uilabel(app.AntInstrumentGridLayout);
            app.EMCenterStatusLabel.Text = 'Status:';
            app.AntennaConnectButton = uibutton(app.AntInstrumentGridLayout, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.AntennaDisconnectButton = uibutton(app.AntInstrumentGridLayout, 'push');
            app.AntennaDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaDisconnectInstruments, true);
            app.AntennaDisconnectButton.Text = 'Disconnect';
            app.MeasurementDelaysLabel = uilabel(app.AntInstrumentGridLayout);
            app.MeasurementDelaysLabel.Text = 'Measurement Delay (s)';
            app.AntennaMeasurementDelayValueField = uieditfield(app.AntInstrumentGridLayout, 'numeric');
            app.AntennaMeasurementDelayValueField.Value = 0.1;
            app.VNATab = uitab(app.AntennaSettings);
            app.VNATab.Title = 'VNA';
            app.AntVNAGridLayout = uigridlayout(app.VNATab);
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.AntVNAGridLayout);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency';
            app.VNAStartFrequency = uieditfield(app.AntVNAGridLayout, 'numeric');
            app.VNAStartFrequency.Limits = [0 Inf];
            app.VNAStartFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAStartFrequency.AllowEmpty = 'on';
            app.VNAStartFrequency.Value = [];
            app.EndFrequencyMHzLabel_6 = uilabel(app.AntVNAGridLayout);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency';
            app.VNAEndFrequency = uieditfield(app.AntVNAGridLayout, 'numeric');
            app.VNAEndFrequency.Limits = [0 Inf];
            app.VNAEndFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAEndFrequency.AllowEmpty = 'on';
            app.VNAEndFrequency.Value = [];
            app.VNAMeasurement = uidropdown(app.AntVNAGridLayout);
            app.VNAMeasurement.Items = {'Gain Characterization', 'Pattern Measurement'};
            app.VNAMeasurement.Value = 'Gain Characterization';
            app.MeasurementDropDown_2Label = uilabel(app.AntVNAGridLayout);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.VNASweepPoints = uieditfield(app.AntVNAGridLayout, 'numeric');
            app.VNASweepPoints.Limits = [0 Inf];
            app.VNASweepPoints.AllowEmpty = 'on';
            app.VNASweepPoints.Value = [];
            app.SweepPointsLabel = uilabel(app.AntVNAGridLayout);
            app.SweepPointsLabel.Text = 'Sweep Points';
            app.Table = uitab(app.AntennaSettings);
            app.Table.Title = 'Table';
            app.AntTableGridLayout = uigridlayout(app.Table);
            app.StopTableButton = uibutton(app.AntTableGridLayout, 'push');
            app.StopTableButton.ButtonPushedFcn = createCallbackFcn(app, @TableStopMovement, true);
            app.StopTableButton.Text = 'Stop Table';
            app.TableNewAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MovetoAngleButton = uibutton(app.AntTableGridLayout, 'push');
            app.MovetoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TableChangeAngle, true);
            app.MovetoAngleButton.Text = 'Move to Angle';
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.AntTableGridLayout);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle ';
            app.TableStartAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableStartAngle.Limits = [-180 180];
            app.TableStartAngle.AllowEmpty = 'on';
            app.TableStartAngle.Placeholder = '(deg)';
            app.TableStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.AntTableGridLayout);
            app.StartFrequectSpanMHzLabel_12.Text = 'Angle Step Size ';
            app.TableStepAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableStepAngle.AllowEmpty = 'on';
            app.TableStepAngle.Placeholder = '(deg)';
            app.TableStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.AntTableGridLayout);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle ';
            app.TableEndAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableEndAngle.Limits = [-180 180];
            app.TableEndAngle.AllowEmpty = 'on';
            app.TableEndAngle.Placeholder = '(deg)';
            app.TableEndAngle.Value = [];
            app.TableSpeedSliderLabel = uilabel(app.AntTableGridLayout);
            app.TableSpeedSliderLabel.Text = 'Table Speed';
            app.TableSpeedSlider = uislider(app.AntTableGridLayout);
            app.TableSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @TableChangeSpeed, true);
            app.TableSpeedSlider.Value = 50;
            app.ThetaSingleSweepSwitch = uiswitch(app.AntTableGridLayout, 'slider');
            app.ThetaSingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.ThetaSingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @ThetaSingleSweepSwitchValueChanged, true);
            app.ThetaSingleSweepSwitch.Value = 'Sweep';
            app.TableSpeedSliderLabel_2 = uilabel(app.AntTableGridLayout);
            app.TableSpeedSliderLabel_2.Text = 'Theta Scan';
            app.TowerTab = uitab(app.AntennaSettings);
            app.TowerTab.Title = 'Tower';
            app.AntTowerGridLayout = uigridlayout(app.TowerTab);
            app.StopTowerButton = uibutton(app.AntTowerGridLayout, 'push');
            app.StopTowerButton.ButtonPushedFcn = createCallbackFcn(app, @TowerStopMovement, true);
            app.StopTowerButton.Text = 'Stop Tower';
            app.TowerNewAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerNewAngle.Limits = [-180 180];
            app.TowerNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MoveTowertoAngleButton = uibutton(app.AntTowerGridLayout, 'push');
            app.MoveTowertoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TowerChangeAngle, true);
            app.MoveTowertoAngleButton.Text = 'Move to Angle';
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.AntTowerGridLayout);
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle ';
            app.TowerStartAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.TowerStartAngle.AllowEmpty = 'on';
            app.TowerStartAngle.Placeholder = '(deg)';
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.AntTowerGridLayout);
            app.StartFrequectSpanMHzLabel_17.Enable = 'off';
            app.StartFrequectSpanMHzLabel_17.Text = 'Angle Step Size ';
            app.TowerStepAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerStepAngle.AllowEmpty = 'on';
            app.TowerStepAngle.Enable = 'off';
            app.TowerStepAngle.Placeholder = '(deg)';
            app.TowerStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.AntTowerGridLayout);
            app.StartFrequectSpanMHzLabel_18.Enable = 'off';
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle ';
            app.TowerEndAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.TowerEndAngle.AllowEmpty = 'on';
            app.TowerEndAngle.Enable = 'off';
            app.TowerEndAngle.Placeholder = '(deg)';
            app.TowerEndAngle.Value = [];
            app.TowerSpeedSliderLabel = uilabel(app.AntTowerGridLayout);
            app.TowerSpeedSliderLabel.Text = 'Tower Speed';
            app.TowerSpeedSlider = uislider(app.AntTowerGridLayout);
            app.TowerSpeedSlider.Value = 50;
            app.PhiSingleSweepSwitch = uiswitch(app.AntTowerGridLayout, 'slider');
            app.PhiSingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.PhiSingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @PhiSingleSweepSwitchValueChanged, true);
            app.PhiSingleSweepSwitch.Value = 'Single';
            app.TableSpeedSliderLabel_3 = uilabel(app.AntTowerGridLayout);
            app.TableSpeedSliderLabel_3.Text = 'Phi Scan';
            app.LinearSliderTab = uitab(app.AntennaSettings);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.AntSliderGridLayout = uigridlayout(app.LinearSliderTab);
            app.AntennaOffsetLabel = uilabel(app.AntSliderGridLayout);
            app.AntennaOffsetLabel.Text = 'Antenna Offset ';
            app.AntennaPhysicalSize = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.AntennaPhysicalSize.Limits = [0 Inf];
            app.AntennaPhysicalSize.ValueDisplayFormat = '%11.4g (cm)';
            app.AntennaPhysicalSize.AllowEmpty = 'on';
            app.AntennaPhysicalSize.ValueChangedFcn = createCallbackFcn(app, @LinearSliderApplyAntennaPhysicalOffset, true);
            app.AntennaPhysicalSize.Value = [];
            app.SliderPositioncmLabel = uilabel(app.AntSliderGridLayout);
            app.SliderPositioncmLabel.Text = 'Slider Current Position';
            app.LinearSliderCurrentPosition = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.LinearSliderCurrentPosition.ValueDisplayFormat = '%11.4g (cm)';
            app.LinearSliderCurrentPosition.AllowEmpty = 'on';
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.LinearSliderCurrentPosition.Value = [];
            app.CurrentSpacingLabel = uilabel(app.AntSliderGridLayout);
            app.CurrentSpacingLabel.Text = 'Current Spacing ';
            app.AntennaCurrentSpacing = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.AntennaCurrentSpacing.ValueDisplayFormat = '%11.4g (m)';
            app.AntennaCurrentSpacing.AllowEmpty = 'on';
            app.AntennaCurrentSpacing.Editable = 'off';
            app.AntennaCurrentSpacing.Value = [];
            app.SpeedPresetDropDownLabel = uilabel(app.AntSliderGridLayout);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SpeedPresetDropDown = uidropdown(app.AntSliderGridLayout);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderChangeSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.StopLinearSliderButton = uibutton(app.AntSliderGridLayout, 'push');
            app.StopLinearSliderButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderStopMovement, true);
            app.StopLinearSliderButton.Text = 'Stop Linear Slider';
            app.MovetoPositionButton = uibutton(app.AntSliderGridLayout, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderChangePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.ConnectButton = uibutton(app.AntSliderGridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderConnect, true);
            app.ConnectButton.Text = 'Connect';
            app.SliderHoming = uibutton(app.AntSliderGridLayout, 'push');
            app.SliderHoming.Text = 'Home';
            app.SliderScan = uibutton(app.AntSliderGridLayout, 'push');
            app.SliderScan.Text = 'Scan';
            app.TestandPlotSettingsPanel = uipanel(app.AntGridLayout);
            app.TestandPlotSettingsPanel.Title = 'Test and Plot Settings';
            app.AntTestSettingsGridLayout = uigridlayout(app.TestandPlotSettingsPanel);
            app.PlotFrequencyGHzDropDownLabel = uilabel(app.AntTestSettingsGridLayout);
            app.PlotFrequencyGHzDropDownLabel.Text = 'Plot Frequency (GHz)';
            app.PlotFrequencyGHzDropDown = uidropdown(app.AntTestSettingsGridLayout);
            app.PlotFrequencyGHzDropDown.Items = {''};
            app.PlotFrequencyGHzDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlotFrequencyGHzDropDown.Value = '';
            app.PlottingAngleLabel = uilabel(app.AntTestSettingsGridLayout);
            app.PlottingAngleLabel.Text = 'Plot Angle (°)';
            app.PlotAngleDropDown = uidropdown(app.AntTestSettingsGridLayout);
            app.PlotAngleDropDown.Items = {''};
            app.PlotAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlotAngleDropDown.Value = '';
            app.StartTestButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.PlotResultsButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.LoadResultsButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.StopTestButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStopMeasurement, true);
            app.StopTestButton.Text = 'Stop Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end